classdef analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        ImportfileMenu           matlab.ui.container.Menu
        ExportfileMenu           matlab.ui.container.Menu
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        DataAnalyzerLabel        matlab.ui.control.Label
        DataInfoPanel            matlab.ui.container.Panel
        QtdEnvironmentsLabel     matlab.ui.control.Label
        QtdObstaclesLabel        matlab.ui.control.Label
        QtdRobotsLabel           matlab.ui.control.Label
        QtdRepetitionsLabel      matlab.ui.control.Label
        FilenameLabel            matlab.ui.control.Label
        MaximumPopulationLabel   matlab.ui.control.Label
        TimePopulationsecLabel   matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        EditField_7              matlab.ui.control.EditField
        EditField_8              matlab.ui.control.EditField
        EditField_9              matlab.ui.control.EditField
        EditField_10             matlab.ui.control.EditField
        EditField_11             matlab.ui.control.EditField
        EditField_12             matlab.ui.control.EditField
        Tree                     matlab.ui.container.Tree
        ManagerNode              matlab.ui.container.TreeNode
        Environment0Node         matlab.ui.container.TreeNode
        Repetition0Node_2        matlab.ui.container.TreeNode
        Population0Node          matlab.ui.container.TreeNode
        Population1Node          matlab.ui.container.TreeNode
        Environment1Node         matlab.ui.container.TreeNode
        Environment2Node         matlab.ui.container.TreeNode
        GraphConfigurationPanel  matlab.ui.container.Panel
        GraphTypeDropDownLabel   matlab.ui.control.Label
        GraphTypeDropDown        matlab.ui.control.DropDown
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        fileName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportfileMenu
        function ImportfileMenuSelected(app, event)
            app.fileName = inputdlg("Type the name of the file you want to import:");
             
             dir = app.fileName('fullpath');
%              dir = dir(:,1:(size(dir,2)-19));
%              
%              size(app.fileName{1})
%              app.fileName{1}(1:3)
%              
%              if(isempty(app.fileName))
%                 app.fileName = 'importData.txt';
%              else
%                 app.fileName = [app.fileName{1} '.txt'];
%              end
%              
%              dir = [dir 'src/Data/' app.fileName]
             
            %{ 
             origin = fullfile(dir, app.fileName)
             destination  = app.tempapp.fileName

            if(isfile(origin))
                 copyfile(origin,destination);
                 
                 fileID = fopen(app.tempapp.fileName, 'r' );
                 while(~feof(fileID))
                    short_word = fscanf(fileID, '%s+');
                    if(short_word=="gen")
                       aux1 = fscanf(fileID, '%s+');
                       aux1 = double(aux1-'0');
                       
                       aux2 = 0;
                       for i=1:(size(aux1,2))
                           aux2 = aux2 + aux1(1,i)*10^(size(aux1,2)-(i));
                       end
                       app.maxGen = aux2;
                       
                       app.maxX = app.maxGen;
                    end
                 end
                genLimit = [ double(app.minGen) double(app.maxGen) ]
                app.maxGenView.Limits = genLimit;
                app.minGenView.Limits = genLimit;
                app.maxGenView.Value = app.maxGen;
                 
                app.configGenesPub = rospublisher('/config_genes','obstacle_avoidance_simulation/ConfigGenes');
                msg = rosmessage(app.configGenesPub);
                
                frewind(fileID);%return to first line
                
                % Send data from the last generation to the robots
                line = fgetl(fileID);
                lastgen = [ 'gen ' num2str(app.maxGen)]
                while(strcmp(line,lastgen)==0)
                    line = fgetl(fileID);
                end
                
                
                % Get data from each robot in generation
                geneNames = ["RobotNumber","SensorActivation","LinearVelocity","AngularVelocity","RotationTime","SensorAngle"];
                formatData = '\trobot%d: %f %f %f %f %f %f %f';
                sizeData = [8 6];
                generationData = fscanf(fileID, formatData,sizeData)';
                
                for robot=1:6
                    nameCont = 1;
                    for i=[1,4,5,6,7,8]
                        newData = generationData(robot,i);  
%                         [ 'msg.' char(geneNames(nameCont)) '(' num2str(robot) ',' num2str(i) ')' ' = newData;' ]
                        eval([ 'msg.' char(geneNames(nameCont)) '(' num2str(robot) ')' ' = newData;' ]);
                        nameCont = nameCont+1;
                    end
                end
                msg.Generation = app.maxGen;
                send(app.configGenesPub,msg);
                fclose(fileID);
            else
                disp([ origin ' do not exists!'])
            end
             app.plotGraphs();
             %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportfileMenu = uimenu(app.FileMenu);
            app.ImportfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfileMenuSelected, true);
            app.ImportfileMenu.Text = 'Import file';
            app.ExportfileMenu = uimenu(app.FileMenu);
            app.ExportfileMenu.Text = 'Export file';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fitness Robots')
            xlabel(app.UIAxes, 'Generation')
            ylabel(app.UIAxes, 'Fitness')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Mean Fitness Robots')
            xlabel(app.UIAxes_2, 'Generation')
            ylabel(app.UIAxes_2, 'Fitness')
            app.DataAnalyzerLabel = uilabel(app.UIFigure);
            app.DataAnalyzerLabel.Text = 'Data Analyzer';
            app.DataInfoPanel = uipanel(app.UIFigure);
            app.DataInfoPanel.Title = 'Data Info';
            app.QtdEnvironmentsLabel = uilabel(app.DataInfoPanel);
            app.QtdEnvironmentsLabel.Text = 'Qtd Environments: ';
            app.QtdObstaclesLabel = uilabel(app.DataInfoPanel);
            app.QtdObstaclesLabel.Text = 'Qtd Obstacles: ';
            app.QtdRobotsLabel = uilabel(app.DataInfoPanel);
            app.QtdRobotsLabel.Text = 'Qtd Robots: ';
            app.QtdRepetitionsLabel = uilabel(app.DataInfoPanel);
            app.QtdRepetitionsLabel.Text = 'Qtd Repetitions: ';
            app.FilenameLabel = uilabel(app.DataInfoPanel);
            app.FilenameLabel.Text = 'File name: ';
            app.MaximumPopulationLabel = uilabel(app.DataInfoPanel);
            app.MaximumPopulationLabel.Text = 'Maximum Population: ';
            app.TimePopulationsecLabel = uilabel(app.DataInfoPanel);
            app.TimePopulationsecLabel.Text = 'Time Population (sec):';
            app.EditField = uieditfield(app.DataInfoPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_7 = uieditfield(app.DataInfoPanel, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.DataInfoPanel, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.DataInfoPanel, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.DataInfoPanel, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.DataInfoPanel, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.DataInfoPanel, 'text');
            app.EditField_12.Editable = 'off';
            app.Tree = uitree(app.UIFigure);
            app.ManagerNode = uitreenode(app.Tree);
            app.ManagerNode.Text = 'Manager';
            app.Environment0Node = uitreenode(app.ManagerNode);
            app.Environment0Node.Text = 'Environment 0';
            app.Repetition0Node_2 = uitreenode(app.Environment0Node);
            app.Repetition0Node_2.Text = 'Repetition 0';
            app.Population0Node = uitreenode(app.Repetition0Node_2);
            app.Population0Node.Text = 'Population 0';
            app.Population1Node = uitreenode(app.Repetition0Node_2);
            app.Population1Node.Text = 'Population 1';
            app.Environment1Node = uitreenode(app.ManagerNode);
            app.Environment1Node.Text = 'Environment 1';
            app.Environment2Node = uitreenode(app.ManagerNode);
            app.Environment2Node.Text = 'Environment 2';
            app.GraphConfigurationPanel = uipanel(app.UIFigure);
            app.GraphConfigurationPanel.Title = 'Graph Configuration';
            app.GraphTypeDropDownLabel = uilabel(app.GraphConfigurationPanel);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.GraphConfigurationPanel);
            app.GraphTypeDropDown.Items = {'Env x Env', 'EnvGenes', 'Pop x Pop', 'Rep x Rep', 'RepGenes'};
            app.GraphTypeDropDown.Value = 'Env x Env';
            app.TextArea = uitextarea(app.GraphConfigurationPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Graph description'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end